classdef electric_field < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        interfaz              matlab.ui.Figure
        Calcular              matlab.ui.control.Button
        Etotal                matlab.ui.control.Table
        tabla_medicion        matlab.ui.control.Table
        PuntodemedicionLabel  matlab.ui.control.Label
        numero_cargas         matlab.ui.control.NumericEditField
        NumerodecargasLabel   matlab.ui.control.Label
        tabla_cargas          matlab.ui.control.Table
        grafica2              matlab.ui.control.UIAxes
        grafica               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Tabla_respaldo
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.tabla_cargas.ColumnFormat = {'numeric', 'numeric', 'numeric'};
            app.tabla_medicion.ColumnFormat = {'numeric', 'numeric', 'numeric'};
            app.tabla_cargas.Data = cell(2,3);
            app.tabla_medicion.Data = cell(1,3);
        end

        % Cell edit callback: tabla_cargas
        function tabla_cargasCellEdit(app, event)
            
        end

        % Value changed function: numero_cargas
        function numero_cargasValueChanged(app, event)
            cantidad_cargas = app.numero_cargas.Value;
            [m,n] = size(app.tabla_cargas.Data);
            app.Tabla_respaldo = app.tabla_cargas.Data;
            if cantidad_cargas < m
                d = cell(cantidad_cargas,n);
                app.Tabla_respaldo = app.tabla_cargas.Data(1:cantidad_cargas, 1:n);
                d(1:cantidad_cargas,1:n) = app.Tabla_respaldo;
                app.tabla_cargas.Data = d;
            else 
                d = cell(cantidad_cargas,n);
                d(1:m,1:n) = app.Tabla_respaldo;
                app.tabla_cargas.Data = d;
            end            
        end

        % Button pushed function: Calcular
        function CalcularButtonPushed(app, event)
        %Campo eléctrico por superposición
        N = 100;
        [m,~] = size(app.tabla_cargas.Data);
        if m < 2 
            minX = cell2mat(app.tabla_cargas.Data(:,2)) - cell2mat(app.tabla_cargas.Data(:,2))*3; maxX = cell2mat(app.tabla_cargas.Data(:,2)) + cell2mat(app.tabla_cargas.Data(:,2))*3;
            minY = cell2mat(app.tabla_cargas.Data(:,3)) - cell2mat(app.tabla_cargas.Data(:,3))*3; maxY = cell2mat(app.tabla_cargas.Data(:,3)) + cell2mat(app.tabla_cargas.Data(:,3))*3;
        else
            dx = abs((max(cell2mat(app.tabla_cargas.Data(:,2)))) + abs(min(cell2mat(app.tabla_cargas.Data(:,2))))) / 2;
            dy = abs((max(cell2mat(app.tabla_cargas.Data(:,3)))) + abs(min(cell2mat(app.tabla_cargas.Data(:,3))))) / 2;
            minX = min(cell2mat(app.tabla_cargas.Data(:,2))) - dx ; maxX = max(cell2mat(app.tabla_cargas.Data(:,2))) + dx;
            minY = min(cell2mat(app.tabla_cargas.Data(:,3))) - dy ; maxY = max(cell2mat(app.tabla_cargas.Data(:,3))) + dy;
        end
        x = linspace(minX,maxX,N);
        y = linspace(minY,maxY,N);
        [xG,yG]= meshgrid(x, y);


        Eo = 8.854187817*10^(-12);
        k = 1/(4*pi*Eo);
        Ex = 0;
        Ey = 0;
        for i=1:m
            Qn = cell2mat(app.tabla_cargas.Data(i, 1));
            xC = cell2mat(app.tabla_cargas.Data(i, 2));
            yC = cell2mat(app.tabla_cargas.Data(i, 3));
            Rx = xG - xC;
            Ry = yG - yC;
            R = sqrt(Rx .^ 2 + Ry .^ 2) .^3;
            Ex = Ex + k .* Qn .* Rx ./ R;
            Ey = Ey + k .* Qn .* Ry ./ R;
        end 
        E = sqrt(Ex .^ 2 + Ey .^ 2);
        
        %componentes x
        u = Ex ./E;
        %componentes y
        v = Ey ./E;
        
        quiver(app.grafica ,xG, yG, u, v );
        %set(h, 'color', [1 0 0], 'linewidth', 1.2);
        
        %Potencial eléctrico por superposición
        V = 0;
        for i=1:m
            Qn = cell2mat(app.tabla_cargas.Data(i, 1));
            xC = cell2mat(app.tabla_cargas.Data(i, 2));
            yC = cell2mat(app.tabla_cargas.Data(i, 3));
            Rx = xG - xC;
            Ry = yG - yC;
            R = sqrt(Rx .^ 2 + Ry .^ 2);
            V = V + ((k .* Qn )./ R);
        end 
        p = pcolor(app.grafica2, xG, yG, V);
        p.EdgeColor = 'none'; 
        colorbar
        shading interp
        colormap jet
        
        contourf(xG, yG, V);
        shading interp
        colormap jet
        colorbar;
        %p = pcolor(app.grafica, xG, yG, V);
        %p.EdgeColor = "none";
        %pcolor(V);
        %colorbar;
       
%         figure(1)
%         surf(xG,yG,V);
%         shading interp
%         colormap jet
%         title('electric potential distribution, v(x,y) in volts','fontsize',14);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.interfaz = uifigure('Visible', 'off');
            app.interfaz.Name = 'MATLAB App';
            app.grafica = uiaxes(app.interfaz);
            title(app.grafica, 'Title')
            xlabel(app.grafica, 'X')
            ylabel(app.grafica, 'Y')
            zlabel(app.grafica, 'Z')
            app.grafica2 = uiaxes(app.interfaz);
            title(app.grafica2, 'Title')
            xlabel(app.grafica2, 'X')
            ylabel(app.grafica2, 'Y')
            zlabel(app.grafica2, 'Z')
            app.tabla_cargas = uitable(app.interfaz);
            app.tabla_cargas.ColumnName = {'Carga'; 'x'; 'y'};
            app.tabla_cargas.ColumnEditable = true;
            app.tabla_cargas.CellEditCallback = createCallbackFcn(app, @tabla_cargasCellEdit, true);
            app.NumerodecargasLabel = uilabel(app.interfaz);
            app.NumerodecargasLabel.Text = 'Numero de cargas';
            app.numero_cargas = uieditfield(app.interfaz, 'numeric');
            app.numero_cargas.Limits = [1 Inf];
            app.numero_cargas.RoundFractionalValues = 'on';
            app.numero_cargas.ValueChangedFcn = createCallbackFcn(app, @numero_cargasValueChanged, true);
            app.numero_cargas.Value = 2;
            app.PuntodemedicionLabel = uilabel(app.interfaz);
            app.PuntodemedicionLabel.Text = 'Punto de medicion';
            app.tabla_medicion = uitable(app.interfaz);
            app.tabla_medicion.ColumnName = {'x'; 'y'; 'z'};
            app.tabla_medicion.RowName = {};
            app.tabla_medicion.ColumnEditable = true;
            app.Etotal = uitable(app.interfaz);
            app.Etotal.ColumnName = {'x'; 'y'; 'z'};
            app.Etotal.RowName = {};
            app.Calcular = uibutton(app.interfaz, 'push');
            app.Calcular.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.Calcular.Text = 'Calcular';
            % Show the figure after all components are created
            app.interfaz.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = electric_field

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.interfaz)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.interfaz)
        end
    end
end